classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TrainingPanel           matlab.ui.container.Panel
        SelectManmadesetButton  matlab.ui.control.Button
        SelectNaturalsetButton  matlab.ui.control.Button
        TrainmodelButton        matlab.ui.control.Button
        TestingPanel            matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        EditField_3             matlab.ui.control.EditField
        SelectTestimageButton   matlab.ui.control.Button
        GenerateMosaicButton    matlab.ui.control.Button
    end


    properties (Access = public)
        mmcount
        ntcount
        fnameloadmm
        fnameloadnt
    end

    methods (Access = private)
    
        function opclass = testmodel(fnameload,mf,Mdl)
    tthist = calhistogram2(fnameload,mf);

    ttline = countline(fnameload,mf);

    tthist = [tthist ttline];

    opclass = {};
    for i = 1:mf
        class_type = predict(Mdl,tthist(i,:));
        opclass(i).fname = fnameload{i};
        opclass(i).class = class_type;
        opclass(i).r = tthist(i,1);
        opclass(i).g = tthist(i,2);
        opclass(i).b = tthist(i,3);
        opclass(i).linenum = tthist(i,4);
        %opclass = [opclass;fnameload{i} class_type{i}];
    end
    
    
end


function linenum = countline(app,fnameload,mf)
    
    linenum = [];
    thr = 30;
    sig = 2;
    for i = 1:mf
        h = imread(fnameload{i});
        BW = edge(rgb2gray(h),'canny',thr/255,sig);
        
        
        %%%% Emphasize line%%%%
        %BW = conv2(BW,ones(3),'same');
        %BW(BW<3) = 0;
        %BW(BW>0) = 1;
        
        [BWx BWy] = size(BW);
        [H,T,R] = hough(BW,'RhoResolution',0.5,'Theta',-90:0.35:89);
        P = houghpeaks(H,300,'Threshold',0.3*max(H(:)),'NHoodSize',[3,3]);
        lines = houghlines(BW,T,R,P);
        plotline = 0;
        for k = 1:length(lines)
            %delete line in edge of piture
            if(abs(lines(k).point1(1)-lines(k).point2(1)) < 10 && (lines(k).point1(1)< 0.1*BWx || lines(k).point1(1) > 0.9*BWx))
                continue;
            end
        
            if(abs(lines(k).point1(2)-lines(k).point2(2)) < 10 && (lines(k).point1(2)< 0.1*BWy || lines(k).point1(2) > 0.9*BWy))
                continue;
            end
            
            c = improfile(BW,[lines(k).point1(1) lines(k).point2(1)],[lines(k).point1(2) lines(k).point2(2)]); %count pixels in line
            [cm cn] = size(c);
            [lcm lcn] = size(c(c==1));
            %delete line that pixel in line < 35%
            if(lcm < 0.35*cm)
                continue;
            end
            
            dis = ((lines(k).point1(1)-lines(k).point2(1))^2 + (lines(k).point1(2)-lines(k).point2(2))^2)^0.5;
        
        
            %delete line length < 5% of maximum side of image
            if(dis < 0.05*max(BWx,BWy)) 
                continue;
            end
        
        
            %delete line that do not have continous segment > 30%
            if(max(conv(c,ones(round(0.3*cm),1))) < round(0.3*cm)-1)
                continue;
            end
            
            
            plotline = plotline +1;
        end
        linenum = [linenum;plotline];
    end

end


function inputhist = calhistogram(fnameload,mf)
    inputhist = [];
    for i = 1:mf
        h = imread(fnameload{i});
        hR = imhist(h(:,:,1));
        hG = imhist(h(:,:,2));
        hB = imhist(h(:,:,3));
        hR = hR./sum(hR(:)); %% normalisation
        hG = hG./sum(hG(:)); %% normalisation
        hB = hB./sum(hB(:)); %% normalisation
        
        inputhist = [inputhist;hR' hG' hB'];
    end
end

function inputhist = calhistogram2(app, fnameload,mf)
    inputhist = [];
    for i = 1:mf
        h = imread(fnameload{i});
        hR = h(:,:,1);
        hG = h(:,:,2);
        hB = h(:,:,3);
        hR = hR./255; %% normalisation
        hG = hG./255; %% normalisation
        hB = hB./255; %% normalisation
        inputhist = [inputhist;mean(mean(hR)) mean(mean(hG)) mean(mean(hB))];
    end
end

function inputhist = calhistogram3(fnameload,mf)
    inputhist = [];
    for i = 1:mf
        h = imread(fnameload{i});
        hR = imhist(h(:,:,1));
        hG = imhist(h(:,:,2));
        hB = imhist(h(:,:,3));
        hR = hR./255; %% normalisation
        hG = hG./255; %% normalisation
        hB = hB./255; %% normalisation
        
        inputhist = [inputhist;hR' hG' hB'];
    end
end

function fname = loadimage(app, str_title)
    %load in folder and sub folders
    start_path = fullfile(matlabroot, 'ImgComp\');
    topLevelFolder = uigetdir(start_path,str_title);
    allSubFolders = genpath(topLevelFolder);
    remain = allSubFolders;
    listOfFolderNames = {};
    while true
        [singleSubFolder, remain] = strtok(remain, ';');
        if isempty(singleSubFolder)
            break;
        end
        listOfFolderNames = [listOfFolderNames singleSubFolder];
    end
    numberOfFolders = length(listOfFolderNames);
    fname = {};
    %iterate through all folder and search for all .jpg files
    for k=1: numberOfFolders
        thisFolder = listOfFolderNames{k};
        filePattern = sprintf('%s/*.jpg', thisFolder);
        baseFileNames = dir(filePattern);
        numberOfTrainingFiles = length(baseFileNames);
   
        for f=1 : numberOfTrainingFiles
            fullFileName = fullfile(thisFolder, baseFileNames(f).name);
            fname{f} = fullFileName;
        end
    end
end


function edw = edgepixel(img)
%%%Calculate number of pixel of edges
    thr = 20;
    sig = 2;
    
    ed = edge(rgb2gray(img),'canny',thr/255,sig);
    ed = imhist(ed); 
    ed = ed./sum(ed(:)); %% normalisation
    edw = ed(2);
end
        
    end


    methods (Access = private)

        % Button pushed function: SelectManmadesetButton
        function SelectManmadesetButtonPushed(app, event)
            app.fnameloadmm = loadimage(app, 'Select folder of training manmade');                %Select folder of source images
            [nf mf] = size(app.fnameloadmm); 
            app.mmcount = mf;

        end

        % Button pushed function: SelectNaturalsetButton
        function SelectNaturalsetButtonPushed(app, event)
            app.fnameloadnt = loadimage(app, 'Select folder of training natural');                %Select folder of source images
            [nf mf] = size(app.fnameloadnt);
            app.ntcount = mf;
        end

        % Button pushed function: TrainmodelButton
        function TrainmodelButtonPushed(app, event)
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait',...
                'Message','Extracting features');
            pause(.5)
            mmhist = calhistogram2(app,app.fnameloadmm,app.mmcount);
            mmline = countline(app, app.fnameloadmm,app.mmcount);
            nthist = calhistogram2(app,app.fnameloadnt,app.ntcount);
            ntline = countline(app,app.fnameloadnt,app.ntcount);
            d.Message = 'Training model';
            X = [mmhist mmline;nthist ntline];
            Y={};
            for i = 1:app.mmcount
                Y{i} = 'MM';
            end

            for i = app.mmcount+1:app.mmcount+app.ntcount
                Y{i} = 'NT';
            end


            Mdl = fitcknn(X,Y,'NumNeighbors',5,'Standardize',1);
            d.close;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TrainingPanel = uipanel(app.UIFigure);
            app.TrainingPanel.Title = 'Training';
            app.SelectManmadesetButton = uibutton(app.TrainingPanel, 'push');
            app.SelectManmadesetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectManmadesetButtonPushed, true);
            app.SelectManmadesetButton.Text = 'Select Manmade set';
            app.SelectNaturalsetButton = uibutton(app.TrainingPanel, 'push');
            app.SelectNaturalsetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNaturalsetButtonPushed, true);
            app.SelectNaturalsetButton.Text = 'Select Natural set';
            app.TrainmodelButton = uibutton(app.TrainingPanel, 'push');
            app.TrainmodelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainmodelButtonPushed, true);
            app.TrainmodelButton.Text = 'Train model';
            app.TestingPanel = uipanel(app.UIFigure);
            app.TestingPanel.Title = 'Testing';
            app.UIAxes = uiaxes(app.TestingPanel);
            title(app.UIAxes, 'Test image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EditField_3 = uieditfield(app.TestingPanel, 'text');
            app.SelectTestimageButton = uibutton(app.TestingPanel, 'push');
            app.SelectTestimageButton.Text = 'Select Test image';
            app.GenerateMosaicButton = uibutton(app.TestingPanel, 'push');
            app.GenerateMosaicButton.Text = 'Generate Mosaic';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end